classdef ea_discfiberexplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ModelSetupDropDownLabel     matlab.ui.control.Label
        ModelSetupDropDown          matlab.ui.control.DropDown
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        ColorButtonPositive         matlab.ui.control.Button
        ColorButtonNegative         matlab.ui.control.Button
        ShowPositiveFibersCheckBox  matlab.ui.control.CheckBox
        ShowNegativeFibersCheckBox  matlab.ui.control.CheckBox
        SplitColorbyGroupVariableCheckBox  matlab.ui.control.CheckBox
        ShowAmountSliderLabel       matlab.ui.control.Label
        ShowAmountSliderPositive    matlab.ui.control.Slider
        LeftSliderLabel             matlab.ui.control.Label
        LeftSliderPositive          matlab.ui.control.Slider
        RightSliderLabel            matlab.ui.control.Label
        RightSliderPositive         matlab.ui.control.Slider
        LeftSlider_2Label           matlab.ui.control.Label
        LeftSliderNegative          matlab.ui.control.Slider
        ShowAmountSlider_2Label     matlab.ui.control.Label
        ShowAmountSliderNegative    matlab.ui.control.Slider
        RightSlider_2Label          matlab.ui.control.Label
        RightSliderNegative         matlab.ui.control.Slider
        VariableofInterestDropDownLabel  matlab.ui.control.Label
        VariableofInterestDropDown  matlab.ui.control.DropDown
        CovariatesListBoxLabel      matlab.ui.control.Label
        CovariatesListBox           matlab.ui.control.ListBox
        ColorBar                    matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        ConnectomeDropDownLabel     matlab.ui.control.Label
        ConnectomeDropDown          matlab.ui.control.DropDown
        LoadTractResultsButton      matlab.ui.control.Button
        SaveTractResultsButton      matlab.ui.control.Button
        CalculateButton             matlab.ui.control.Button
        OpenLeadGroupProjectDiscriminativeFibertractButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: SplitColorbyGroupVariableCheckBox
        function SplitColorbyGroupVariableCheckBoxValueChanged(app, event)
            value = app.SplitColorbyGroupVariableCheckBox.Value;
            switch value
                case 1
                    app.ShowNegativeFibersCheckBox.Enable = 'off';
                    app.ShowNegativeFibersCheckBox.Value = 0;
                    app.ColorButtonNegative.Enable = 'off';
                    app.ShowAmountSliderNegative.Enable = 'off';
                    app.LeftSliderNegative.Enable = 'off';
                    app.RightSliderNegative.Enable = 'off';
                    app.ColorButtonPositive.Enable = 'off';
                case 0
                    app.ShowNegativeFibersCheckBox.Enable = 'on';
                    app.ColorButtonNegative.Enable = 'on';
                    app.ShowAmountSliderNegative.Enable = 'on';
                    app.LeftSliderNegative.Enable = 'on';
                    app.RightSliderNegative.Enable = 'on';
                    app.ColorButtonPositive.Enable = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ModelSetupDropDownLabel = uilabel(app.UIFigure);
            app.ModelSetupDropDownLabel.Text = 'Model Setup';
            app.ModelSetupDropDown = uidropdown(app.UIFigure);
            app.ModelSetupDropDown.Items = {'T-Tests / VTAs', 'Spearman''s Correlation / Efields'};
            app.ModelSetupDropDown.Value = 'T-Tests / VTAs';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ColorButtonPositive = uibutton(app.Tab, 'push');
            app.ColorButtonPositive.Text = 'Color';
            app.ColorButtonNegative = uibutton(app.Tab, 'push');
            app.ColorButtonNegative.Text = 'Color';
            app.ShowPositiveFibersCheckBox = uicheckbox(app.Tab);
            app.ShowPositiveFibersCheckBox.Text = 'Show Positive Fibers';
            app.ShowNegativeFibersCheckBox = uicheckbox(app.Tab);
            app.ShowNegativeFibersCheckBox.Text = 'Show Negative Fibers';
            app.SplitColorbyGroupVariableCheckBox = uicheckbox(app.Tab);
            app.SplitColorbyGroupVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitColorbyGroupVariableCheckBoxValueChanged, true);
            app.SplitColorbyGroupVariableCheckBox.Text = 'Split & Color by Group Variable';
            app.ShowAmountSliderLabel = uilabel(app.Tab);
            app.ShowAmountSliderLabel.Text = 'Show Amount';
            app.ShowAmountSliderPositive = uislider(app.Tab);
            app.LeftSliderLabel = uilabel(app.Tab);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSliderPositive = uislider(app.Tab);
            app.RightSliderLabel = uilabel(app.Tab);
            app.RightSliderLabel.Text = 'Right';
            app.RightSliderPositive = uislider(app.Tab);
            app.LeftSlider_2Label = uilabel(app.Tab);
            app.LeftSlider_2Label.Text = 'Left';
            app.LeftSliderNegative = uislider(app.Tab);
            app.ShowAmountSlider_2Label = uilabel(app.Tab);
            app.ShowAmountSlider_2Label.Text = 'Show Amount';
            app.ShowAmountSliderNegative = uislider(app.Tab);
            app.RightSlider_2Label = uilabel(app.Tab);
            app.RightSlider_2Label.Text = 'Right';
            app.RightSliderNegative = uislider(app.Tab);
            app.VariableofInterestDropDownLabel = uilabel(app.Tab);
            app.VariableofInterestDropDownLabel.Text = 'Variable of Interest';
            app.VariableofInterestDropDown = uidropdown(app.Tab);
            app.CovariatesListBoxLabel = uilabel(app.Tab);
            app.CovariatesListBoxLabel.Text = 'Covariates';
            app.CovariatesListBox = uilistbox(app.Tab);
            app.ColorBar = uiaxes(app.Tab);
            title(app.ColorBar, 'Colorbar')
            xlabel(app.ColorBar, 'X')
            ylabel(app.ColorBar, 'Y')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.ConnectomeDropDownLabel = uilabel(app.UIFigure);
            app.ConnectomeDropDownLabel.Text = 'Connectome';
            app.ConnectomeDropDown = uidropdown(app.UIFigure);
            app.ConnectomeDropDown.Items = {'', ''};
            app.ConnectomeDropDown.Value = '';
            app.LoadTractResultsButton = uibutton(app.UIFigure, 'push');
            app.LoadTractResultsButton.Text = 'Load Tract Results';
            app.SaveTractResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveTractResultsButton.Text = 'Save Tract Results';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.OpenLeadGroupProjectDiscriminativeFibertractButton = uibutton(app.UIFigure, 'push');
            app.OpenLeadGroupProjectDiscriminativeFibertractButton.Text = 'Open Lead-Group Project / Discriminative Fibertract';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ea_discfiberexplorer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end